classdef myApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        PullupPanel          matlab.ui.container.Panel
        GridLayout2_3        matlab.ui.container.GridLayout
        PullupVideoLayout    matlab.ui.container.GridLayout
        PullupControlLayout  matlab.ui.container.GridLayout
        Pullup_RepLabel      matlab.ui.control.Label
        Pullup_WeightLabel   matlab.ui.control.Label
        Pullup_HeightLabel   matlab.ui.control.Label
        Pullup_StatusLabel   matlab.ui.control.Label
        Pullup_WeightButton  matlab.ui.control.Button
        Pullup_StartButton   matlab.ui.control.Button
        Pullup_ManualButton  matlab.ui.control.Button
        Pullup_LoadButton    matlab.ui.control.Button
        Pullup_UIAxes        matlab.ui.control.UIAxes
        GridLayout3_3        matlab.ui.container.GridLayout
        PullupsLabel         matlab.ui.control.Label
        PullupBgImage        matlab.ui.control.Image
    end

    
    properties (Access = private)
        % Pull-up analysis state
        Pullup_VideoObj
        Pullup_BarHeight
        Pullup_BgFrame
        Pullup_IsManualMode logical = false
        Pullup_WeightCount double = 0
        Pullup_hImage
        Pullup_hHeadLine
        Pullup_motionThreshold double = 100000
    end
    
    methods (Access = private)
        
        
        function Pullup_onImageClick(app, event)
            if app.Pullup_IsManualMode
                clickPos = event.IntersectionPoint;
                newY = clickPos(2);
    
                % Update bar
                app.Pullup_setBar(newY);
    
                % Exit manual mode
                app.Pullup_IsManualMode = false;
                app.Pullup_StatusLabel.Text = 'Height Updated! Press Start.';
                app.UIFigure.Pointer = 'arrow';
            end
        end
        function Pullup_setBar(app, heightVal)
            app.Pullup_BarHeight = heightVal;
    
            % Remove previous bar visuals
            delete(findobj(app.Pullup_UIAxes, 'Tag', 'PullupBarVisual'));
    
            % Draw new bar line
            yline(app.Pullup_UIAxes, heightVal, 'r', 'LineWidth', 3, 'Tag', 'PullupBarVisual');
    
            % Add label
            text(app.Pullup_UIAxes, 20, heightVal - 15, 'BAR LIMIT', ... 
                'Color', 'r', 'FontWeight', 'bold', 'Tag', 'PullupBarVisual');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Pull-up Analysis';
            app.PullupBgImage.Parent = app.PullupPanel;
            app.PullupBgImage.Position = [1 1 app.PullupPanel.Position(3) app.PullupPanel.Position(4)];
            uistack(app.PullupBgImage, 'bottom');
        end

        % Button pushed function: Pullup_LoadButton
        function Pullup_LoadButtonPushed(app, event)
            % Select file
            [f, p] = uigetfile({'*.mp4;*.MOV', 'Video Files'}, 'Select Video');
            if isequal(f, 0)
                return;
            end
        
            % Initial load
            app.Pullup_VideoObj = VideoReader(fullfile(p, f));
            app.Pullup_BgFrame = readFrame(app.Pullup_VideoObj);
        
            % Show first frame
            app.Pullup_hImage = image(app.Pullup_UIAxes, app.Pullup_BgFrame);
            app.Pullup_UIAxes.YDir = 'reverse';
            app.Pullup_UIAxes.XLim = [0 size(app.Pullup_BgFrame, 2)];
            app.Pullup_UIAxes.YLim = [0 size(app.Pullup_BgFrame, 1)];
        
            % Connect click on image to manual bar selection
            app.Pullup_hImage.ButtonDownFcn = createCallbackFcn(app, @Pullup_onImageClick, true);
        
            % Bar detection logic (Pull-up mode only)
            app.Pullup_StatusLabel.Text = 'Detecting bar...'; drawnow;
            [detectedH, found] = detectBarLogic(app.Pullup_BgFrame);
        
            if found
                app.Pullup_setBar(detectedH);
                app.Pullup_StatusLabel.Text = 'Bar Detected!';
            else
                app.Pullup_setBar(size(app.Pullup_BgFrame, 1) * 0.3);
                app.Pullup_StatusLabel.Text = 'Bar Not Found';
            end
        
            % Enable controls
            app.Pullup_ManualButton.Enable = 'on';
            app.Pullup_StartButton.Enable  = 'on';
            app.Pullup_WeightButton.Enable = 'on';
            app.Pullup_LoadButton.Enable   = 'off';
            app.Pullup_StatusLabel.Text = 'Ready.';
        end

        % Button pushed function: Pullup_ManualButton
        function Pullup_ManualButtonPushed(app, event)
            if isempty(app.Pullup_BgFrame)
                uialert(app.UIFigure, 'Please load a video first.', 'Warning');
                return;
            end
            app.Pullup_IsManualMode = true;
            app.Pullup_StatusLabel.Text = 'CLICK ON THE BAR in the image...';
            app.Pullup_StatusLabel.FontColor = [1 0 0];
            app.UIFigure.Pointer = 'crosshair';
        end

        % Button pushed function: Pullup_StartButton
        function Pullup_StartButtonPushed(app, event)
            % Lock UI
            app.Pullup_LoadButton.Enable   = 'off';
            app.Pullup_StartButton.Enable  = 'off';
            app.Pullup_ManualButton.Enable = 'off';
            app.Pullup_WeightButton.Enable = 'off';
            app.Pullup_StatusLabel.Text    = 'Analyzing...';
        
            if isempty(app.Pullup_VideoObj) || ~isvalid(app.Pullup_VideoObj)
                app.Pullup_StatusLabel.Text = 'No video loaded.';
                return;
            end
        
            % Reset video
            app.Pullup_VideoObj.CurrentTime = 0;
        
            repsData   = [];
            allScores  = [];
            allHeights = [];
        
            repCount   = 0;
            frameCount = 0;
        
            isMoving   = false;
            startFrame = 0;
            maxScore   = 0;
            heightAtMax = NaN;
        
            motionThreshold = app.Pullup_motionThreshold;
        
            % Prepare head-line graphic
            if isempty(app.Pullup_hHeadLine) || ~isvalid(app.Pullup_hHeadLine)
                hold(app.Pullup_UIAxes, 'on');
                app.Pullup_hHeadLine = plot(app.Pullup_UIAxes, ...
                    [1, size(app.Pullup_BgFrame, 2)], [0, 0], ...
                    'b--', 'LineWidth', 2, 'Visible', 'off');
                hold(app.Pullup_UIAxes, 'off');
            end
        
            % Main loop
            while true
                if ~isvalid(app.UIFigure) || isempty(app.Pullup_VideoObj) || ~isvalid(app.Pullup_VideoObj)
                    return;
                end
        
                if ~hasFrame(app.Pullup_VideoObj)
                    break;
                end
        
                frame = readFrame(app.Pullup_VideoObj);
                frameCount = frameCount + 1;
        
                % Pull-up motion logic
                [score, height] = calcMotionLogic(app.Pullup_BgFrame, frame, 15);
        
                allScores(end+1) = score;
                if isempty(height)
                    allHeights(end+1) = NaN;
                else
                    allHeights(end+1) = height;
                end
        
                % Update image
                app.Pullup_hImage.CData = frame;
        
                if ~isempty(height)
                    app.Pullup_hHeadLine.YData = [height, height];
                    app.Pullup_hHeadLine.Visible = 'on';
        
                    if ~isempty(app.Pullup_BarHeight)
                        dist = app.Pullup_BarHeight - height;
                        app.Pullup_HeightLabel.Text = sprintf('   Dist: %.0f px', dist);
                        if dist > 0
                            app.Pullup_HeightLabel.FontColor = [0 0.8 0];
                        else
                            app.Pullup_HeightLabel.FontColor = [1 1 1];
                        end
                    else
                        app.Pullup_HeightLabel.Text = sprintf('   Y: %.0f', height);
                        app.Pullup_HeightLabel.FontColor = [0 0 0];
                    end
                else
                    app.Pullup_hHeadLine.Visible = 'off';
                end
        
                % Motion state machine
                if ~isMoving
                    if score > motionThreshold
                        isMoving   = true;
                        startFrame = frameCount;
                        maxScore   = score;
                        heightAtMax = height;
                    end
                else
                    if score > maxScore
                        maxScore = score;
                        if ~isempty(height)
                            heightAtMax = height;
                        end
                    end
        
                    if score < motionThreshold
                        isMoving = false;
                        duration = (frameCount - startFrame) / app.Pullup_VideoObj.FrameRate;
        
                        if duration > 0.5
                            metricData = app.Pullup_BarHeight - heightAtMax;
                            repsData = [repsData; duration, heightAtMax, metricData, startFrame, frameCount];
        
                            repCount = repCount + 1;
                            app.Pullup_RepLabel.Text = sprintf('   Reps: %d', repCount);
                        end
                    end
                end
        
                drawnow limitrate;
            end
        
            % After loop
            if isvalid(app.UIFigure)
                app.Pullup_StatusLabel.Text = 'Done!';
                app.Pullup_LoadButton.Enable   = 'on';
                app.Pullup_StartButton.Enable  = 'on';
                app.Pullup_ManualButton.Enable = 'on';
                app.Pullup_WeightButton.Enable = 'on';
        
                if exist('generateReport.m', 'file') == 2
                    generateReport(repsData, allScores, allHeights, ...
                        app.Pullup_BarHeight, motionThreshold, app.Pullup_WeightCount);
                end
            end
        end

        % Button pushed function: Pullup_WeightButton
        function Pullup_WeightButtonPushed(app, event)
            if isempty(app.Pullup_BgFrame)
                uialert(app.UIFigure, 'Please load a video first.', 'Warning');
                return;
            end
        
            app.Pullup_StatusLabel.Text = 'Select area...';
        
            [count, success] = detectWeights(app.Pullup_BgFrame);
        
            if success
                app.Pullup_WeightCount = count;
                app.Pullup_WeightLabel.Text = sprintf('   Kg: %d', count);
                app.Pullup_StatusLabel.Text = 'Weights Updated!';
                uialert(app.UIFigure, sprintf('Detected %d kg.', count), 'Success');
            else
                app.Pullup_StatusLabel.Text = 'Detection cancelled.';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Pull-up Analysis';
            app.PullupPanel = uipanel(app.UIFigure);
            app.GridLayout2_3 = uigridlayout(app.PullupPanel);
            app.PullupBgImage = uiimage(app.GridLayout2_3);
            app.PullupBgImage.ImageSource = fullfile(pathToMLAPP, 'bg.jpeg');
            app.GridLayout3_3 = uigridlayout(app.GridLayout2_3);
            app.PullupsLabel = uilabel(app.GridLayout3_3);
            app.PullupsLabel.Text = 'Pull-up Analysis';
            app.PullupVideoLayout = uigridlayout(app.GridLayout2_3);
            app.Pullup_UIAxes = uiaxes(app.PullupVideoLayout);
            app.Pullup_UIAxes.XAxisLocation = 'origin';
            app.Pullup_UIAxes.XTick = [];
            app.Pullup_UIAxes.YTick = [];
            app.PullupControlLayout = uigridlayout(app.PullupVideoLayout);
            app.Pullup_LoadButton = uibutton(app.PullupControlLayout, 'push');
            app.Pullup_LoadButton.ButtonPushedFcn = createCallbackFcn(app, @Pullup_LoadButtonPushed, true);
            app.Pullup_LoadButton.Text = 'Load Video';
            app.Pullup_ManualButton = uibutton(app.PullupControlLayout, 'push');
            app.Pullup_ManualButton.ButtonPushedFcn = createCallbackFcn(app, @Pullup_ManualButtonPushed, true);
            app.Pullup_ManualButton.Text = 'Set Bar Height';
            app.Pullup_StartButton = uibutton(app.PullupControlLayout, 'push');
            app.Pullup_StartButton.ButtonPushedFcn = createCallbackFcn(app, @Pullup_StartButtonPushed, true);
            app.Pullup_StartButton.Text = 'Start Analysis';
            app.Pullup_WeightButton = uibutton(app.PullupControlLayout, 'push');
            app.Pullup_WeightButton.ButtonPushedFcn = createCallbackFcn(app, @Pullup_WeightButtonPushed, true);
            app.Pullup_WeightButton.Text = 'Set Weight';
            app.Pullup_StatusLabel = uilabel(app.PullupControlLayout);
            app.Pullup_StatusLabel.Text = '  Welcome';
            app.Pullup_HeightLabel = uilabel(app.PullupControlLayout);
            app.Pullup_HeightLabel.Text = '   Dist: --';
            app.Pullup_WeightLabel = uilabel(app.PullupControlLayout);
            app.Pullup_WeightLabel.Text = '   Weight: --';
            app.Pullup_RepLabel = uilabel(app.PullupControlLayout);
            app.Pullup_RepLabel.Text = '   Reps: --';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = myApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end